classdef CallCuraGui_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1        matlab.ui.Figure
        plotEval       matlab.ui.control.UIAxes
        plotLog9       matlab.ui.control.UIAxes
        plotLog8       matlab.ui.control.UIAxes
        plotLog7       matlab.ui.control.UIAxes
        plotLog6       matlab.ui.control.UIAxes
        plotLog5       matlab.ui.control.UIAxes
        plotLog4       matlab.ui.control.UIAxes
        plotLog3       matlab.ui.control.UIAxes
        plotLog2       matlab.ui.control.UIAxes
        plotLog1       matlab.ui.control.UIAxes
        plotMic        matlab.ui.control.UIAxes
        plotLogEval    matlab.ui.control.UIAxes
        plotMicEval    matlab.ui.control.UIAxes
        plotLog10      matlab.ui.control.UIAxes
        filename       matlab.ui.control.Label
        sliderLeft     matlab.ui.control.Slider
        playMic        matlab.ui.control.Button
        playLog1       matlab.ui.control.Button
        playLog2       matlab.ui.control.Button
        playLog3       matlab.ui.control.Button
        playLog4       matlab.ui.control.Button
        playLog5       matlab.ui.control.Button
        playLog6       matlab.ui.control.Button
        playLog7       matlab.ui.control.Button
        playLog8       matlab.ui.control.Button
        playLog9       matlab.ui.control.Button
        noCall         matlab.ui.control.Button
        playMicEval    matlab.ui.control.Button
        playLogEval    matlab.ui.control.Button
        text3          matlab.ui.control.Label
        evalLog1       matlab.ui.control.Button
        evalLog2       matlab.ui.control.Button
        evalLog3       matlab.ui.control.Button
        evalLog4       matlab.ui.control.Button
        evalLog5       matlab.ui.control.Button
        evalLog6       matlab.ui.control.Button
        evalLog7       matlab.ui.control.Button
        evalLog8       matlab.ui.control.Button
        evalLog9       matlab.ui.control.Button
        submit         matlab.ui.control.Button
        sliderRight    matlab.ui.control.Slider
        checkbox       matlab.ui.control.Button
        redo           matlab.ui.control.Button
        redoEditVoc    matlab.ui.control.EditField
        redoEditSet    matlab.ui.control.EditField
        text5          matlab.ui.control.Label
        text6          matlab.ui.control.Label
        start          matlab.ui.control.Button
        quit           matlab.ui.control.Button
        playLog10      matlab.ui.control.Button
        evalLog10      matlab.ui.control.Button
        text7          matlab.ui.control.Label
        text8          matlab.ui.control.Label
        text10         matlab.ui.control.Label
        text11         matlab.ui.control.Label
        text12         matlab.ui.control.Label
        text13         matlab.ui.control.Label
        text14         matlab.ui.control.Label
        text15         matlab.ui.control.Label
        TextAreaLabel  matlab.ui.control.Label
        message        matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function CallCuraGui_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to CallCuraGui (see VARARGIN)
%             
%             % Choose default command line output for CallCuraGui
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
        end

        % Button pushed function: start
        function startButtonPushed(app, event)
            CallCurafkt('Start');
        end

        % Button pushed function: quit
        function quitButtonPushed(app, event)
            CallCurafkt('Quit');
        end

        % Button pushed function: noCall
        function noCallButtonPushed(app, event)
            CallCurafkt('NoCall');
        end

        % Button pushed function: submit
        function submitButtonPushed(app, event)
            CallCurafkt('Submit');
        end

        % Button pushed function: redo
        function redoButtonPushed(app, event)
            CallCurafkt('Redo');
        end

        % Button pushed function: checkbox
        function checkboxButtonPushed(app, event)
            CallCurafkt('Checkbox');
        end

        % Button pushed function: playMic
        function playMicButtonPushed(app, event)
            PlayCallfkt('PlayMic');
        end

        % Button pushed function: playLog1
        function playLog1ButtonPushed(app, event)
            PlayCallfkt('PlayLog1');
        end

        % Button pushed function: playLog2
        function playLog2ButtonPushed(app, event)
            PlayCallfkt('PlayLog2');
        end

        % Button pushed function: playLog3
        function playLog3ButtonPushed(app, event)
            PlayCallfkt('PlayLog3');
        end

        % Button pushed function: playLog4
        function playLog4ButtonPushed(app, event)
            PlayCallfkt('PlayLog4');
        end

        % Button pushed function: playLog5
        function playLog5ButtonPushed(app, event)
            PlayCallfkt('PlayLog5');
        end

        % Button pushed function: playLog6
        function playLog6ButtonPushed(app, event)
            PlayCallfkt('PlayLog6');
        end

        % Button pushed function: playLog7
        function playLog7ButtonPushed(app, event)
            PlayCallfkt('PlayLog7');
        end

        % Button pushed function: playLog8
        function playLog8ButtonPushed(app, event)
            PlayCallfkt('PlayLog8');
        end

        % Button pushed function: playLog9
        function playLog9ButtonPushed(app, event)
            PlayCallfkt('PlayLog9');
        end

        % Button pushed function: playLog10
        function playLog10ButtonPushed(app, event)
            PlayCallfkt('PlayLog10');
        end

        % Button pushed function: playMicEval
        function playMicEvalButtonPushed(app, event)
            PlayCallfkt('PlayMicEval');
        end

        % Button pushed function: playLogEval
        function playLogEvalButtonPushed(app, event)
            PlayCallfkt('PlayLogEval');
        end

        % Button pushed function: evalLog1
        function evalLog1ButtonPushed(app, event)
            CallCurafkt('EvalLog1');
        end

        % Button pushed function: evalLog2
        function evalLog2ButtonPushed(app, event)
            CallCurafkt('EvalLog2');
        end

        % Button pushed function: evalLog3
        function evalLog3ButtonPushed(app, event)
            CallCurafkt('EvalLog3');
        end

        % Button pushed function: evalLog4
        function evalLog4ButtonPushed(app, event)
            CallCurafkt('EvalLog4');
        end

        % Button pushed function: evalLog5
        function evalLog5ButtonPushed(app, event)
            CallCurafkt('EvalLog5');
        end

        % Button pushed function: evalLog6
        function evalLog6ButtonPushed(app, event)
            CallCurafkt('EvalLog6');
        end

        % Button pushed function: evalLog7
        function evalLog7ButtonPushed(app, event)
            CallCurafkt('EvalLog7');
        end

        % Button pushed function: evalLog8
        function evalLog8ButtonPushed(app, event)
            CallCurafkt('EvalLog8');
        end

        % Button pushed function: evalLog9
        function evalLog9ButtonPushed(app, event)
            CallCurafkt('EvalLog9');
        end

        % Button pushed function: evalLog10
        function evalLog10ButtonPushed(app, event)
            CallCurafkt('EvalLog10');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'CallCuraGui';
            app.plotEval = uiaxes(app.figure1);
            app.plotLog9 = uiaxes(app.figure1);
            app.plotLog8 = uiaxes(app.figure1);
            app.plotLog7 = uiaxes(app.figure1);
            app.plotLog6 = uiaxes(app.figure1);
            app.plotLog5 = uiaxes(app.figure1);
            app.plotLog4 = uiaxes(app.figure1);
            app.plotLog3 = uiaxes(app.figure1);
            app.plotLog2 = uiaxes(app.figure1);
            app.plotLog1 = uiaxes(app.figure1);
            app.plotMic = uiaxes(app.figure1);
            app.plotLogEval = uiaxes(app.figure1);
            app.plotMicEval = uiaxes(app.figure1);
            app.plotLog10 = uiaxes(app.figure1);
            app.filename = uilabel(app.figure1);
            app.filename.Text = 'Filename';
            app.sliderLeft = uislider(app.figure1);
            app.sliderLeft.Limits = [0 1];
            app.sliderLeft.MajorTicks = [];
            app.sliderLeft.MinorTicks = [];
            app.playMic = uibutton(app.figure1, 'push');
            app.playMic.ButtonPushedFcn = createCallbackFcn(app, @playMicButtonPushed, true);
            app.playMic.Text = 'PlayMic';
            app.playLog1 = uibutton(app.figure1, 'push');
            app.playLog1.ButtonPushedFcn = createCallbackFcn(app, @playLog1ButtonPushed, true);
            app.playLog1.Text = 'PlayLog1';
            app.playLog2 = uibutton(app.figure1, 'push');
            app.playLog2.ButtonPushedFcn = createCallbackFcn(app, @playLog2ButtonPushed, true);
            app.playLog2.Text = 'PlayLog2';
            app.playLog3 = uibutton(app.figure1, 'push');
            app.playLog3.ButtonPushedFcn = createCallbackFcn(app, @playLog3ButtonPushed, true);
            app.playLog3.Text = 'PlayLog3';
            app.playLog4 = uibutton(app.figure1, 'push');
            app.playLog4.ButtonPushedFcn = createCallbackFcn(app, @playLog4ButtonPushed, true);
            app.playLog4.Text = 'PlayLog4';
            app.playLog5 = uibutton(app.figure1, 'push');
            app.playLog5.ButtonPushedFcn = createCallbackFcn(app, @playLog5ButtonPushed, true);
            app.playLog5.Text = 'PlayLog5';
            app.playLog6 = uibutton(app.figure1, 'push');
            app.playLog6.ButtonPushedFcn = createCallbackFcn(app, @playLog6ButtonPushed, true);
            app.playLog6.Text = 'PlayLog6';
            app.playLog7 = uibutton(app.figure1, 'push');
            app.playLog7.ButtonPushedFcn = createCallbackFcn(app, @playLog7ButtonPushed, true);
            app.playLog7.Text = 'PlayLog7';
            app.playLog8 = uibutton(app.figure1, 'push');
            app.playLog8.ButtonPushedFcn = createCallbackFcn(app, @playLog8ButtonPushed, true);
            app.playLog8.Text = 'PlayLog8';
            app.playLog9 = uibutton(app.figure1, 'push');
            app.playLog9.ButtonPushedFcn = createCallbackFcn(app, @playLog9ButtonPushed, true);
            app.playLog9.Text = 'PlayLog9';
            app.noCall = uibutton(app.figure1, 'push');
            app.noCall.ButtonPushedFcn = createCallbackFcn(app, @noCallButtonPushed, true);
            app.noCall.Text = 'No Call';
            app.playMicEval = uibutton(app.figure1, 'push');
            app.playMicEval.ButtonPushedFcn = createCallbackFcn(app, @playMicEvalButtonPushed, true);
            app.playMicEval.Text = 'PlayMic';
            app.playLogEval = uibutton(app.figure1, 'push');
            app.playLogEval.ButtonPushedFcn = createCallbackFcn(app, @playLogEvalButtonPushed, true);
            app.playLogEval.Text = 'PlayLog';
            app.text3 = uilabel(app.figure1);
            app.text3.Text = {'indicate if'; 'Listener'};
            app.evalLog1 = uibutton(app.figure1, 'push');
            app.evalLog1.ButtonPushedFcn = createCallbackFcn(app, @evalLog1ButtonPushed, true);
            app.evalLog1.Text = 'EvalLog1';
            app.evalLog2 = uibutton(app.figure1, 'push');
            app.evalLog2.ButtonPushedFcn = createCallbackFcn(app, @evalLog2ButtonPushed, true);
            app.evalLog2.Text = 'EvalLog2';
            app.evalLog3 = uibutton(app.figure1, 'push');
            app.evalLog3.ButtonPushedFcn = createCallbackFcn(app, @evalLog3ButtonPushed, true);
            app.evalLog3.Text = 'EvalLog3';
            app.evalLog4 = uibutton(app.figure1, 'push');
            app.evalLog4.ButtonPushedFcn = createCallbackFcn(app, @evalLog4ButtonPushed, true);
            app.evalLog4.Text = 'EvalLog4';
            app.evalLog5 = uibutton(app.figure1, 'push');
            app.evalLog5.ButtonPushedFcn = createCallbackFcn(app, @evalLog5ButtonPushed, true);
            app.evalLog5.Text = 'EvalLog5';
            app.evalLog6 = uibutton(app.figure1, 'push');
            app.evalLog6.ButtonPushedFcn = createCallbackFcn(app, @evalLog6ButtonPushed, true);
            app.evalLog6.Text = 'EvalLog6';
            app.evalLog7 = uibutton(app.figure1, 'push');
            app.evalLog7.ButtonPushedFcn = createCallbackFcn(app, @evalLog7ButtonPushed, true);
            app.evalLog7.Text = 'EvalLog7';
            app.evalLog8 = uibutton(app.figure1, 'push');
            app.evalLog8.ButtonPushedFcn = createCallbackFcn(app, @evalLog8ButtonPushed, true);
            app.evalLog8.Text = 'EvalLog8';
            app.evalLog9 = uibutton(app.figure1, 'push');
            app.evalLog9.ButtonPushedFcn = createCallbackFcn(app, @evalLog9ButtonPushed, true);
            app.evalLog9.Text = 'EvalLog9';
            app.submit = uibutton(app.figure1, 'push');
            app.submit.ButtonPushedFcn = createCallbackFcn(app, @submitButtonPushed, true);
            app.submit.Text = 'Submit';
            app.sliderRight = uislider(app.figure1);
            app.sliderRight.Limits = [0 1];
            app.sliderRight.MajorTicks = [];
            app.sliderRight.MinorTicks = [];
            app.checkbox = uibutton(app.figure1, 'push');
            app.checkbox.ButtonPushedFcn = createCallbackFcn(app, @checkboxButtonPushed, true);
            app.checkbox.Text = 'V';
            app.redo = uibutton(app.figure1, 'push');
            app.redo.ButtonPushedFcn = createCallbackFcn(app, @redoButtonPushed, true);
            app.redo.Text = 'Redo';
            app.redoEditVoc = uieditfield(app.figure1, 'text');
            app.redoEditVoc.Value = '1';
            app.redoEditSet = uieditfield(app.figure1, 'text');
            app.redoEditSet.Value = '1';
            app.text5 = uilabel(app.figure1);
            app.text5.Text = 'Voc#';
            app.text6 = uilabel(app.figure1);
            app.text6.Text = 'Set#';
            app.start = uibutton(app.figure1, 'push');
            app.start.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.start.Text = 'Start';
            app.quit = uibutton(app.figure1, 'push');
            app.quit.ButtonPushedFcn = createCallbackFcn(app, @quitButtonPushed, true);
            app.quit.Text = 'Quit';
            app.playLog10 = uibutton(app.figure1, 'push');
            app.playLog10.ButtonPushedFcn = createCallbackFcn(app, @playLog10ButtonPushed, true);
            app.playLog10.Text = 'PlayLog10';
            app.evalLog10 = uibutton(app.figure1, 'push');
            app.evalLog10.ButtonPushedFcn = createCallbackFcn(app, @evalLog10ButtonPushed, true);
            app.evalLog10.Text = 'EvalLog10';
            app.text7 = uilabel(app.figure1);
            app.text7.Text = {'indicate if'; 'Caller'};
            app.text8 = uilabel(app.figure1);
            app.text8.Text = {'bat is'; 'hearing call'; ''};
            app.text10 = uilabel(app.figure1);
            app.text10.Text = 'no call';
            app.text11 = uilabel(app.figure1);
            app.text11.Text = {'computer'; 'guess:'; ''};
            app.text12 = uilabel(app.figure1);
            app.text12.Text = {'bat is'; 'calling'; ''};
            app.text13 = uilabel(app.figure1);
            app.text13.Text = {'call'; ''};
            app.text14 = uilabel(app.figure1);
            app.text14.Text = {'user guess:'; ''; ''};
            app.text15 = uilabel(app.figure1);
            app.text15.Text = 'mouse buttons: left=choose    middle=zoom in     right=reset zoom';
            app.TextAreaLabel = uilabel(app.figure1);
            app.TextAreaLabel.Text = 'Text Area';
            app.message = uitextarea(app.figure1);
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CallCuraGui_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @CallCuraGui_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end